classdef new_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DataTree                    matlab.ui.container.Tree
        ProcessinglevelButtonGroup  matlab.ui.container.ButtonGroup
        SessButton                  matlab.ui.control.RadioButton
        RunButton                   matlab.ui.control.RadioButton
        SubjButton                  matlab.ui.control.RadioButton
        GroupButton                 matlab.ui.control.RadioButton
        SignalListBox               matlab.ui.control.ListBox
        PlotButton                  matlab.ui.control.Button
        ChannelDropDown             matlab.ui.control.DropDown
        ChannelDropDownLabel        matlab.ui.control.Label
        PlotTypeButtonGroup         matlab.ui.container.ButtonGroup
        ConcButton                  matlab.ui.control.RadioButton
        ODButton                    matlab.ui.control.RadioButton
        RawdataButton               matlab.ui.control.RadioButton
        EditOptionButton            matlab.ui.control.Button
        RunImageReconExp            matlab.ui.control.Button
        BrowseData                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Property % Description
        AdvApp
        ds % data source
        cache % current process
        sel % user selection
        cfg
    end
    
    methods (Access = private)


        %% This function update the "Signal List" box on the UI
        % It check which plot type the user selected Raw/OD/Conc.
        % e.g: user select Conc -> it show (like Hbo/HbR/HbT)
        % After creating this list, the function updates the SignalListBox on the UI 
        function updateSignalList(app)
            plotType = app.PlotTypeButtonGroup.SelectedObject.Text;
            firstRun = app.ds.groupResult.subjs(1).sess(1).runs(1);
            firstRun.Load();
            app.ds.waveLength = firstRun.acquired.probe.wavelengths;
            items = {};

            % user select Raw/OD -> it will show (like 670nm, 850nm)
            if strcmp(plotType, 'Raw data') || strcmp(plotType, 'OD')
                for i = 1:numel(app.ds.waveLength)
                    items{i} = sprintf('%dnm', round(app.ds.waveLength(i)));
                end
                
            % user select Conc -> it show (like Hbo/HbR/HbT)
            elseif strcmp(plotType, 'Conc')
                items{1} = 'HbO';
                items{2} = 'HbR';
                items{3} = 'HbT';
            end

            % update Signal List Box
            app.SignalListBox.Items = items;
            app.SignalListBox.Value = items{1};
            drawnow;
        end
        

        %%  Parse channel label text "TxX–RxY" into numeric ID
        function [s,d] = parseChannelLabel(~,label)
            % extract 2 numbers: after 'Tx' and after 'Rx', with any non-digit separator between
            tok = regexp(label,'Tx(\d+)[^\d]+Rx(\d+)','tokens','once');
            s = str2double(tok{1});
            d = str2double(tok{2});
        end


        %% Parse the integer number before 'nm'
        function waveLengthIdx = nmLabelToIndex(app, nmLabel)
            waveLengthIdx = [];
            nm = sscanf(nmLabel, '%dnm');
            if isempty(nm) || isempty(app.ds.wls)
                waveLengthIdx = [];
                return;
            end
            % Find the first index where wavelength equals that number
            waveLengthIdx = find(app.ds.wls == nm, 1);
        end
   

        %% Get channel pair list Tx#-Rx#
        function getChannelList(app)
            % I pick first run to get channel list
            firstRun = app.ds.groupResult.subjs(1).sess(1).runs(1);
            firstRun.Load();
            assignin('base','firstRun',firstRun); % load into workspace for testing/debugging
            test = firstRun.acquired;
            assignin('base', 'test', test);
            ml = firstRun.acquired.data.measurementList;

            if isempty(ml)
                uialert(app.UIFigure, 'measurementList is empty.', 'Error');
                return;
            end

            sourceArray = zeros(numel(ml), 1);
            detectorArray = zeros(numel(ml), 1);

            %fill the arrays
            for i = 1:(numel(ml))
                sourceArray(i) = ml(i).sourceIndex;
                detectorArray(i) = ml(i).detectorIndex;
            end

            % Combine Tx and Rx into one nx2 array
            % make a list of UNIQUE [Tx,Rx] pairs
            % keep only the first occurrence of each pair
            pairsRaw = [sourceArray detectorArray];
            pairs = []; % start with empty list

            for i = 1:size(pairsRaw, 1)
                isExist = false;
                row = pairsRaw(i,:);

                % check if this pair is already in 'pairs'
                for j = 1:size(pairs, 1)
                    if all(row == pairs(j,:))
                        isExist = true;
                        break;
                    end 
                end

                % If the pair is new, add it toe the list
                if isExist == false
                    pairs = [pairs; row];
                end
            end


            % create label and map, update channel to ListBox of channel
            rowSize = size(pairs, 1);
            channelMap(rowSize, 1) = struct('label', "", 'source', 0, 'detector', 0);

            for i = 1:rowSize
                source = pairs(i, 1);
                detector = pairs(i, 2);

                label = sprintf('Tx%d-Rx%d', source, detector);

                channelMap(i).label = label;
                channelMap(i).source = source;
                channelMap(i).detector = detector;

                %fprintf('Channel %d: s=%d, d=%d, label=%s\n', i, source, detector, label);
            end


            % update channelDropDown on GUI
            items = {channelMap.label}.';
            app.ChannelDropDown.Items = items;
            app.ChannelDropDown.Value = app.ChannelDropDown.Items{1};
            drawnow;

            % i need this one for find wavelength INDEX
            app.ds.wls = firstRun.GetWls();
        end

        
        %% rebasePath
        % Reset all file paths in the group (group → subj → sess → run)
        % so they point to the new root folder.
        function rebasePath(~, root, group)
            parentPath = filesepStandard(root, 'dir');
            
            for i = 1:numel(group.subjs)
                group.subjs(i).path = parentPath;
                for j = 1:numel(group.subjs(i).sess)
                    group.subjs(i).sess(j).path = parentPath;
                    for k = 1:numel(group.subjs(i).sess(j).runs)
                        group.subjs(i).sess(j).runs(k).path = parentPath;
                    end
                end
            end     
        end


        %%
        % Build the tree view like:
        % Group 
        %      Subject
        %             Session 
        %                    Run
        % Each node get a label (the output filename) and a NodeData struct that stores its Index 
        % [groupIdx subjIdx sessIdx runIdx]. later on figure out exactly which file the user clicked in the tree.
        function buildTreeFromGroup(app, group)
            ig = max(1, group.iGroup);
        
            groupNode = uitreenode(app.DataTree, ...
                "Text", char(group.GetOutputFilename()), ...
                "NodeData", struct('type','group','idx',[ig 0 0 0]));
        
            for isubj = 1:numel(group.subjs)
                subj = group.subjs(isubj);
                subjNode = uitreenode(groupNode, ...
                    "Text", char(subj.GetOutputFilename()), ...
                    "NodeData", struct('type','subj','idx',[ig isubj 0 0]));
        
                for isess = 1:numel(subj.sess)
                    sess = subj.sess(isess);
                    sessNode = uitreenode(subjNode, ...
                        "Text", char(sess.GetOutputFilename()), ...
                        "NodeData", struct('type','sess','idx',[ig isubj isess 0]));
        
                    for irun = 1:numel(sess.runs)
                        run = sess.runs(irun);
                        uitreenode(sessNode, ...
                            "Text", char(run.GetOutputFilename()), ...
                            "NodeData", struct('type','run','idx',[ig isubj isess irun]));
                    end
                    expand(sessNode);
                end
                expand(subjNode);
            end
            expand(groupNode);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % DEFAULT DEFAULT
            appDir  = fileparts(mfilename('fullpath')); % .../SSDOT-main/GUI
            rootDir = fileparts(appDir); % .../SSDOT-main
        

            addpath(genpath(fullfile(rootDir,'code')));
            addpath(genpath(fullfile(rootDir,'homer_function')));
            % addpath(genpath(fullfile(rootDir,'homer_class')));
            

            % DEFAULT VAR, user can change these parameter on GUI

            % SYNC UI

            % Other
    
        end

        % Button pushed function: BrowseData
        function BrowseDataButtonPushed(app, event)
            %% Open a folder selection to allow user to choose(browse) the data folder
            %% Then after select will immediately load GroupResults.m data and show the subjects/group/run list
            
            % let user pick Homer output folder eg: ST001 folder
            folderPath = uigetdir(pwd, 'Select the output homer folder');

            if isequal(folderPath,0)
                return; % user hit cancel
            end

            % ST001/derivatives/homer/groupResults.mat
            groupPath = fullfile(folderPath, 'derivatives', 'homer', 'groupResults.mat');
            S = load(groupPath, 'group');
            app.ds.groupResult = S.group;
            assignin('base','groupResult', app.ds.groupResult);


            % After load groupResults.mat, reset all run/session paths to your current folder before calling
            rebasePath(app, folderPath, app.ds.groupResult);

            % Build the DataTree UI to display hierarchy  
            % (Group → Subject → Session → Run) in the app’s tree panel 
            buildTreeFromGroup(app, app.ds.groupResult);


            getChannelList(app);
            updateSignalList(app);
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            updateSignalList(app);
            
        end

        % Selection changed function: ProcessinglevelButtonGroup
        function ProcessinglevelButtonGroupSelectionChanged(app, event)
            app.sel.processingLevel = app.ProcessinglevelButtonGroup.SelectedObject.Text;
            app.DataTree.SelectedNodes =[];

            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% Wrap everything to check before hit plot button
            %% Basic guards
            if ~isfield(app.ds,'groupResult') || isempty(app.ds.groupResult)
                uialert(app.UIFigure,'Load a dataset first','No Data');
                return;
            end
            % if ~isfield(app,'sel') || isempty(app.sel) || ~isfield(app.sel,'file')
            %     uialert(app.UIFigure,'Select a RUN in the tree.','Info');
            %     return;
            % end
            if isempty(app.ChannelDropDown.Value)
                uialert(app.UIFigure,'Pick a channel (Tx–Rx).','Info'); 
                return;
            end
            if isempty(app.SignalListBox.Value)
                uialert(app.UIFigure,'Pick at least one signal to plot.','Info'); 
                return;
            end


            % parse dropdown text "Tx#-Rx#" into NUMERIC IDs
            [source, detector] = app.parseChannelLabel(app.ChannelDropDown.Value);
            selectSignal = app.SignalListBox.Value; % this one is wavelength (670nm) or Hbo Hbt...

            % user slect processing level type and plot type
            ProcessLevelType = app.ProcessinglevelButtonGroup.SelectedObject.Text;
            PlotType = app.PlotTypeButtonGroup.SelectedObject.Text;

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            legendLabels = {};
            
            switch ProcessLevelType
                case 'Run'
                    % get the actual run object based on selection in tree
                    plotData = app.ds.groupResult.subjs(app.sel.file.subjIdx).sess(app.sel.file.sessIdx).runs(app.sel.file.runIdx);
                    plotData.Load();
                    switch PlotType
                        case 'Raw'
                            % question: raw data is not stored in output file
                            uialert(app.UIFigure, 'I dont find raw data in output file', 'Error');

                        case 'OD'
                            % get optical density by using homer3 GetVar
                            dod = plotData.procStream.output.GetVar('dod'); % GetVar using homer3 function
                            % channel info (Tx,Rx,wavelength)
                            ml = dod.measurementList;

                            % build arrays of Tx, Rx, and wavelength index
                            % To search which column in the DataTimeSeries matix matches the user’s chosen (Tx, Rx, wavelength).
                            sourceArrayOD = zeros(numel(ml), 1);
                            detectorArrayOD = zeros(numel(ml), 1);
                            waveLengthArrayOD = zeros(numel(ml), 1);

                            
                            %fill the arrays
                            for i = 1:(numel(ml))
                                sourceArrayOD(i) = ml(i).sourceIndex;
                                detectorArrayOD(i) = ml(i).detectorIndex;
                                waveLengthArrayOD(i) = ml(i).wavelengthIndex;
                            end

                            x = dod.GetTime(); % time vector
                            y = dod.GetDataTimeSeries(); % OD data matrix

                            % loop over user selected wavelength
                            for i = 1:numel(selectSignal)
                                % eg: map 850nm -> index
                                waveLengthIdx = app.nmLabelToIndex(selectSignal{i});
                                
                                % find the column index that match
                                % source, detector, wavelength
                                col = find(source == sourceArrayOD & ...
                                    detector == detectorArrayOD &  ...
                                    waveLengthIdx == waveLengthArrayOD, 1, 'first');

                                % plot OD curve
                                plot(app.UIAxes, x, y(:,col), 'LineWidth', 1.3)
                                legendLabels{end + 1} = sprintf('Tx%d-Rx%d - %s', source, detector, selectSignal{i});
                            end


                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Intensity (a.u.)');
                            title(app.UIAxes, sprintf('Optical Density — Tx%d–Rx%d', source, detector));


                        case 'Conc'
                            % get concentration result data
                            dc = plotData.procStream.output.GetVar('dc');
                            
                            % channel info of concentration (Tx, Rx, HbO/HbR/HbT)
                            ml = dc.measurementList;

                            
                            % go through measurementList to find the first entry that matches
                            % the user’s selected (Tx,Rx) pair. Save its index as Hbo_idx, which marks
                            % the starting column for the HbO/HbR/HbT triplet in the data matrix
                            for i = 1:(numel(ml))
                                s_idx = ml(i).sourceIndex;
                                d_idx = ml(i).detectorIndex;
                                if (source == s_idx && detector == d_idx)
                                    Hbo_idx = i;
                                    break;
                                end
                            end

                            x = dc.GetTime();
                            y = dc.GetDataTimeSeries();

                            for i = 1:numel(selectSignal)
                                % user select HbO/HbR/HbT
                                selection = lower(selectSignal{i});
                                if strcmp(selection, 'hbo') , k = 0;
                                elseif strcmp(selection, 'hbr'), k = 1;
                                elseif strcmp(selection, 'hbt'), k = 2;
                                end

                                % plot concentration result
                                plot(app.UIAxes, x, y(:,Hbo_idx + k), 'LineWidth', 1.3);
                                names = {'HbO', 'HbR', 'HbT'};

                                legendLabels{end+1} = sprintf('%s — Tx%d–Rx%d', names{k+1}, source, detector);
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes, sprintf('(\\M = mol/L'));
                            title(app.UIAxes, sprintf('Conc — Tx%d–Rx%d', source, detector));
                    end

                case 'Sess'
                    plotData = app.ds.groupResult.subj(app.sel.file.subjIdx).sess(app.sel.file.sessIdx);
                case 'Subj'
                    plotData = app.ds.groupResult.subj(app.sel.file.subjIdx);
                case 'Group'
                    plotData = app.ds.groupResult;

            end

            legend(app.UIAxes, legendLabels, 'Location','northeast')
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            grid(app.UIAxes,'on');
            hold(app.UIAxes,'off');


        end

        % Selection changed function: DataTree
        function DataTreeSelectionChanged(app, event)
            % Handle selection in the DataTree (group/subj/sess/run)
            % Get whatever node the user just clicked on Data tree list
            selectedNodes = event.SelectedNodes;
            nodeData = selectedNodes.NodeData;

            % Figure out what level the user *wants* to work at (from processing level buttons)
            requiredLevel = lower(strtrim(app.ProcessinglevelButtonGroup.SelectedObject.Text));

            % If they clicked the wrong kind of node (ex: processing level = run but clicked subj folder on data list)
            if ~strcmp(nodeData.type, requiredLevel)
                app.DataTree.SelectedNodes = [];
                uialert(app.UIFigure, "Please select a " + requiredLevel + " file.", "Wrong level");
                return;
            end

            % Save the selected node info into app.sel.file for later use 
            % nodeData.idx = [groupIndex subjIndex sessIndex runIndex]
            app.sel.file = struct();
            app.sel.file.groupIdx = nodeData.idx(1);
            app.sel.file.subjIdx = nodeData.idx(2);
            app.sel.file.sessIdx = nodeData.idx(3);
            app.sel.file.runIdx = nodeData.idx(4);

        end

        % Button pushed function: EditOptionButton
        function EditOptionButtonPushed(app, event)
            % Open the advanced settings window
            % allow user change some parameters
            % Disable the button while the dialog is open
            app.EditOptionButton.Enable = "off";
        
            % Open Advanced settings dialog
            app.AdvApp = AdvancedSetting(app.cfg, @app.onAdvancedApply, @app.onAdvancedClose);
            app.AdvApp.UIFigure.WindowStyle = 'modal';
        
            % Ensure the button is re-enabled if user closes the window via [X]
            %app.AdvApp.UIFigure.CloseRequestFcn = @(src,evt) app.onAdvClosed();

        end

        % Button pushed function: RunImageReconExp
        function RunImageReconExpButtonPushed(app, event)
            %% nothing...
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BrowseData = uibutton(app.UIFigure, 'push');
            app.BrowseData.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseData.Text = 'Browse';
            app.RunImageReconExp = uibutton(app.UIFigure, 'push');
            app.RunImageReconExp.ButtonPushedFcn = createCallbackFcn(app, @RunImageReconExpButtonPushed, true);
            app.RunImageReconExp.Text = 'Run reconstruct';
            app.EditOptionButton = uibutton(app.UIFigure, 'push');
            app.EditOptionButton.ButtonPushedFcn = createCallbackFcn(app, @EditOptionButtonPushed, true);
            app.EditOptionButton.Text = 'Edit Option';
            app.PlotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.RawdataButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.RawdataButton.Text = 'Raw data';
            app.RawdataButton.Value = true;
            app.ODButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ODButton.Text = 'OD';
            app.ConcButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ConcButton.Text = 'Conc';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalListBox = uilistbox(app.UIFigure);
            app.SignalListBox.Items = {};
            app.SignalListBox.Multiselect = 'on';
            app.SignalListBox.Value = {};
            app.ProcessinglevelButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessinglevelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessinglevelButtonGroupSelectionChanged, true);
            app.ProcessinglevelButtonGroup.Title = 'Processing level';
            app.GroupButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.GroupButton.Text = 'Group';
            app.SubjButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SubjButton.Text = 'Subj';
            app.RunButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.RunButton.Text = 'Run';
            app.RunButton.Value = true;
            app.SessButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SessButton.Text = 'Sess';
            app.DataTree = uitree(app.UIFigure);
            app.DataTree.SelectionChangedFcn = createCallbackFcn(app, @DataTreeSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end